classdef FloydAppDesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AlgoritmodeFloydUIFigure    matlab.ui.Figure
        CargarmatrizdeadyacenciasButton  matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        MatrizdedistanciasLabel     matlab.ui.control.Label
        MatrizdedistanciasTextArea  matlab.ui.control.TextArea
        MatrizderutasTextAreaLabel  matlab.ui.control.Label
        MatrizderutasTextArea       matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        filename    % Ruta completa de la matriz de adyacencias
        A           % Matriz de adyacencias
        G           % Grafo de la matriz A
        D           % Matriz de distancias
        P           % Matriz de rutas
    end
    
    methods (Access = private)
        
function [D, R] = Floyd(~, AdjMatrix)
% *INPUT:* 
% AdjMax: Matriz adyacente que representa una gráfica dirigida con pesos
%
% *OUTPUT:*
% D: distancia al nodo destino
% R: matriz de recorridos

% Determinar número de nodos
n = min(size(AdjMatrix));

% Inicializar matriz de recorridos
R = repmat(1:n, n, 1);

% Inicializar matriz de distancias
% cambiando todos los ceros por infinitos
D = AdjMatrix;
D(AdjMatrix == 0) = inf;


% Iteramos sobre todos los nodos
for k=1:n
    
    % Creamos arreglo de índices para saltar 
    % la k-ésima fila y columna
    index = 1:n;
    index(k) = [];
    
    % Los elementos de la diagonal son -1
    D(k,k) = -1;
    
    % Iteramos sobre filas
    for i = index
        
        % Iteramos sobre columnas
        for j = index
            
            % Si alguna fila tiene valor infinito, saltar la iteración
            if D(i,k) == inf
                continue;
            end
            
            % Si alguna columna tiene valor infinito, saltar la iteración
            if D(k,j) == inf
                continue;
            end
            
            % Si la suma de la distancia de la fila i y la columna j
            % es menor que la distancia i,j, cambiamos la entrada
            % y actualizamos la matriz de rutas
            if D(i,k) + D(k,j) < D(i,j)
                D(i,j) = D(i,k) + D(k,j);
                R(i,j) = k;
            end
        end
    end
end
end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarmatrizdeadyacenciasButton
        function CargarmatrizdeadyacenciasButtonPushed(app, event)
            % Abrir cuadro de diálogo para elegir matriz
            [file, path] = uigetfile("*.csv");
            app.filename = strcat(path, file);
            
            % Cargar archivo en matriz A
            app.A = readmatrix(app.filename);
            
            % Crear grafo
            app.G = digraph(app.A,"omitselfloops");
            
            % Visualizar grafo con pesos
            plot(app.UIAxes, app.G, 'EdgeLabel', app.G.Edges.Weight);
            
            % Aplicar algoritmo de Floyd
            [app.D, app.P] = app.Floyd(app.A);
            
            % Mostrar las matrices obtenidas
            [n,~] = size(app.A);
            
            valueD = [];
            
            for i=1:n
                valueD = [valueD; {num2str(app.D(i,:))}];
            end
            
            app.MatrizdedistanciasTextArea.Value = valueD;
            
            valueP = [];
            
            for i=1:n
                valueP = [valueP; {num2str(app.P(i,:))}];
            end
            
            app.MatrizderutasTextArea.Value = valueP;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AlgoritmodeFloydUIFigure = uifigure('Visible', 'off');
            app.AlgoritmodeFloydUIFigure.Name = 'Algoritmo de Floyd';
            app.CargarmatrizdeadyacenciasButton = uibutton(app.AlgoritmodeFloydUIFigure, 'push');
            app.CargarmatrizdeadyacenciasButton.ButtonPushedFcn = createCallbackFcn(app, @CargarmatrizdeadyacenciasButtonPushed, true);
            app.CargarmatrizdeadyacenciasButton.Text = {'Cargar matriz '; 'de adyacencias'};
            app.UIAxes = uiaxes(app.AlgoritmodeFloydUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.MatrizdedistanciasLabel = uilabel(app.AlgoritmodeFloydUIFigure);
            app.MatrizdedistanciasLabel.Text = {'Matriz de'; 'distancias'};
            app.MatrizdedistanciasTextArea = uitextarea(app.AlgoritmodeFloydUIFigure);
            app.MatrizderutasTextAreaLabel = uilabel(app.AlgoritmodeFloydUIFigure);
            app.MatrizderutasTextAreaLabel.Text = {'Matriz de'; 'rutas'};
            app.MatrizderutasTextArea = uitextarea(app.AlgoritmodeFloydUIFigure);
            % Show the figure after all components are created
            app.AlgoritmodeFloydUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FloydAppDesigner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AlgoritmodeFloydUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AlgoritmodeFloydUIFigure)
        end
    end
end